classdef acousticPressureSlice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        TabGroup                  matlab.ui.container.TabGroup
        PhasedelaysTab            matlab.ui.container.Tab
        SourceDropDownLabel       matlab.ui.control.Label
        SourceDropDown            matlab.ui.control.DropDown
        ViewphasedelaysButton     matlab.ui.control.Button
        BrowseButton              matlab.ui.control.Button
        SliceplaneTab             matlab.ui.container.Tab
        SliceDropDown             matlab.ui.control.DropDown
        Label                     matlab.ui.control.Label
        SliceConstField           matlab.ui.control.NumericEditField
        unitsLabel                matlab.ui.control.Label
        AxeslimitsTab             matlab.ui.container.Tab
        hLabel                    matlab.ui.control.Label
        vLabel                    matlab.ui.control.Label
        fromEditFieldLabel        matlab.ui.control.Label
        hStart                    matlab.ui.control.NumericEditField
        fromEditFieldLabel_2      matlab.ui.control.Label
        vStart                    matlab.ui.control.NumericEditField
        toLabel                   matlab.ui.control.Label
        hEnd                      matlab.ui.control.NumericEditField
        toLabel_2                 matlab.ui.control.Label
        vEnd                      matlab.ui.control.NumericEditField
        unitsLabel_2              matlab.ui.control.Label
        unitsLabel_3              matlab.ui.control.Label
        UnitsTab                  matlab.ui.container.Tab
        UnitsDropDownLabel        matlab.ui.control.Label
        UnitsDropDown             matlab.ui.control.DropDown
        ResolutionTab             matlab.ui.container.Tab
        HorizontalEditFieldLabel  matlab.ui.control.Label
        HorizontalEditField       matlab.ui.control.NumericEditField
        pixelsLabel               matlab.ui.control.Label
        pixelsLabel_2             matlab.ui.control.Label
        VerticalEditFieldLabel    matlab.ui.control.Label
        VerticalEditField         matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        phases = zeros(64,1); % vector of 64 phase delays
        xlim = [-0.025 0.025]; % x-axis limits
        ylim = [-0.025 0.025]; % y-axis limits
        zlim = [0.02 0.06]; % z-axis limits
        hRes = 151; % horizontal resolution
        vRes = 151; % vertical resolution
        slicePlane = 'x'; % slice plane, either 'x', 'y', or 'z'
        c = 0; % slice plane constant (x = c)
        units = 'mm';
    end

    methods (Access = private)
    
        function redraw(app)
            Cdata = zeros(app.vRes,app.hRes);
            
            switch app.slicePlane
                case 'x'
                    V = acousticPressure3D(app.phases,[app.c app.c],1,app.ylim,app.hRes,app.zlim,app.vRes);
                    Cdata = reshape(V,app.hRes,app.vRes)';
                case 'y'
                    V = acousticPressure3D(app.phases,app.xlim,app.hRes,[app.c app.c],1,app.zlim,app.vRes);
                    Cdata = reshape(V,app.hRes,app.vRes)';
                case 'z'
                    V = acousticPressure3D(app.phases,app.xlim,app.hRes,app.ylim,app.vRes,[app.c app.c],1);
                    Cdata = V(:,:,1);
            end
            
            imagesc(app.UIAxes,Cdata);
            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.YDir = 'normal';
            updateLabels(app);
        end

        
        function updateLabels(app)
            app.unitsLabel.Text = app.units;
            app.unitsLabel_2.Text = app.units;
            app.unitsLabel_3.Text = app.units;
            
            unitConv = 1;
            if strcmp('mm',app.units)
                unitConv = 1000;
            end
            app.SliceConstField.Value = app.c*unitConv;
            graph = get(app.UIAxes,'Children');
            switch app.slicePlane
                case 'x'
                    app.hLabel.Text = 'y';
                    app.vLabel.Text = 'z';
                    app.hStart.Value = app.ylim(1)*unitConv;
                    app.hEnd.Value = app.ylim(2)*unitConv;
                    app.vStart.Value = app.zlim(1)*unitConv;
                    app.vEnd.Value = app.zlim(2)*unitConv;
                    if ~isempty(graph)
                        set(graph,'XData',linspace(app.ylim(1)*unitConv,app.ylim(2)*unitConv,app.hRes));
                        set(graph,'YData',linspace(app.zlim(1)*unitConv,app.zlim(2)*unitConv,app.vRes));
                        set(app.UIAxes,{'xLim','yLim'},{app.ylim*unitConv,app.zlim*unitConv});
                        app.UIAxes.XLabel.String = sprintf('y [%s]',app.units);
                        app.UIAxes.YLabel.String = sprintf('z [%s]',app.units);
                    end
                case 'y'
                    app.hLabel.Text = 'x';
                    app.vLabel.Text = 'z';
                    app.hStart.Value = app.xlim(1)*unitConv;
                    app.hEnd.Value = app.xlim(2)*unitConv;
                    app.vStart.Value = app.zlim(1)*unitConv;
                    app.vEnd.Value = app.zlim(2)*unitConv;
                    if ~isempty(graph)
                        set(graph,'XData',linspace(app.xlim(1)*unitConv,app.xlim(2)*unitConv,app.hRes));
                        set(graph,'YData',linspace(app.zlim(1)*unitConv,app.zlim(2)*unitConv,app.vRes));
                        set(app.UIAxes,{'xLim','yLim'},{app.xlim*unitConv,app.zlim*unitConv});
                        app.UIAxes.XLabel.String = sprintf('x [%s]',app.units);
                        app.UIAxes.YLabel.String = sprintf('z [%s]',app.units);
                    end
                case 'z'
                    app.hLabel.Text = 'x';
                    app.vLabel.Text = 'y';
                    app.hStart.Value = app.xlim(1)*unitConv;
                    app.hEnd.Value = app.xlim(2)*unitConv;
                    app.vStart.Value = app.ylim(1)*unitConv;
                    app.vEnd.Value = app.ylim(2)*unitConv;
                    if ~isempty(graph)
                        set(graph,'XData',linspace(app.xlim(1)*unitConv,app.xlim(2)*unitConv,app.hRes));
                        set(graph,'YData',linspace(app.ylim(1)*unitConv,app.ylim(2)*unitConv,app.vRes));
                        set(app.UIAxes,{'xLim','yLim'},{app.xlim*unitConv,app.ylim*unitConv});
                        app.UIAxes.XLabel.String = sprintf('x [%s]',app.units);
                        app.UIAxes.YLabel.String = sprintf('y [%s]',app.units);
                    end
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            colormap(app.UIAxes,'hot');
            cbar = colorbar(app.UIAxes);
            %cbar.Label.String = 'Pressure [Pa]';
            app.SliceDropDown.Value = app.slicePlane;
            app.HorizontalEditField.Value = app.hRes;
            app.VerticalEditField.Value = app.vRes;
            redraw(app);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            newPhase = [];
            switch app.SourceDropDown.Value
                case 'workspace'
                    varName = inputdlg('Enter name of the workspace variable');
                    if ~isempty(varName)
                        try 
                            newPhase = evalin('base',varName{1});
                        catch Ex
                            errordlg(sprintf('Cannot import workspace variable ''%s''!',varName{1}));
                            return;
                        end
                    else
                        return;
                    end
                case '.mat file'
                    defaultFileName = fullfile(pwd(), '*.mat');
                    [baseFileName, folder] = uigetfile(defaultFileName, 'Select a .mat file');
                    if baseFileName ~= 0
                        fullFileName = fullfile(folder, baseFileName);
                        storedStructure = load(fullFileName);
                        if ~isempty(storedStructure)
                            newPhase = storedStructure{1};
                        else
                            return;
                        end
                    else
                        return;
                    end
            end
            if numel(newPhase) == 64 && isnumeric(newPhase)
                app.phases = reshape(newPhase,64,1);
                redraw(app);
            else
                errordlg('Cannot assign new phases. The input vector must have 64 numerical values.');
            end
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            value = app.UnitsDropDown.Value;
            app.units = value;
            updateLabels(app);
        end

        % Button pushed function: ViewphasedelaysButton
        function ViewphasedelaysButtonPushed(app, event)
            plotPhases(app.phases);
        end

        % Value changed function: SliceDropDown
        function SliceDropDownValueChanged(app, event)
            value = app.SliceDropDown.Value;
            app.slicePlane = value;
            switch value
                case 'x'
                    app.c = mean(app.xlim);
                case 'y'
                    app.c = mean(app.ylim);
                case 'z'
                    app.c = mean(app.zlim);
            end
            redraw(app);
        end

        % Value changed function: SliceConstField
        function SliceConstFieldValueChanged(app, event)
            value = app.SliceConstField.Value;
            unitConv = 1;
            if strcmp('mm',app.units)
                unitConv = 0.001;
            end
            app.c = value*unitConv;
            redraw(app);         
        end

        % Value changed function: hStart
        function hStartValueChanged(app, event)
            value = app.hStart.Value;
            if value > app.hEnd.Value
                errordlg('Lower limit must not be higher than upper limit');
                updateLabels(app);
                return;
            end
            unitConv = 1;
            if strcmp('mm',app.units)
                unitConv = 0.001;
            end
            switch app.slicePlane
                case 'x'
                    app.ylim(1) = value*unitConv;
                case 'y'
                    app.xlim(1) = value*unitConv;
                case 'z'
                    app.xlim(1) = value*unitConv;
            end  
            redraw(app);
        end

        % Value changed function: vStart
        function vStartValueChanged(app, event)
            value = app.vStart.Value;
            if value > app.vEnd.Value
                errordlg('Lower limit must not be higher than upper limit');
                updateLabels(app);
                return;
            end
            unitConv = 1;
            if strcmp('mm',app.units)
                unitConv = 0.001;
            end
            switch app.slicePlane
                case 'x'
                    app.zlim(1) = value*unitConv;
                case 'y'
                    app.zlim(1) = value*unitConv;
                case 'z'
                    app.ylim(1) = value*unitConv;
            end 
            redraw(app);
        end

        % Value changed function: hEnd
        function hEndValueChanged(app, event)
            value = app.hEnd.Value;
            if value < app.hStart.Value
                errordlg('Upper limit must not be smaller than lower limit');
                updateLabels(app);
                return;
            end
            unitConv = 1;
            if strcmp('mm',app.units)
                unitConv = 0.001;
            end
            switch app.slicePlane
                case 'x'
                    app.ylim(2) = value*unitConv;
                case 'y'
                    app.xlim(2) = value*unitConv;
                case 'z'
                    app.xlim(2) = value*unitConv;
            end 
            redraw(app);
        end

        % Value changed function: vEnd
        function vEndValueChanged(app, event)
            value = app.vEnd.Value;
            if value < app.vStart.Value
                errordlg('Upper limit must not be smaller than lower limit');
                updateLabels(app);
                return;
            end
            unitConv = 1;
            if strcmp('mm',app.units)
                unitConv = 0.001;
            end
            switch app.slicePlane
                case 'x'
                    app.zlim(2) = value*unitConv;
                case 'y'
                    app.zlim(2) = value*unitConv;
                case 'z'
                    app.ylim(2) = value*unitConv;
            end 
            redraw(app);
        end

        % Value changed function: HorizontalEditField
        function HorizontalEditFieldValueChanged(app, event)
            value = app.HorizontalEditField.Value;
            if value > 0
                app.hRes = value;
                redraw(app);
            else
                errordlg('Value must be greater than 0');
            end
        end

        % Value changed function: VerticalEditField
        function VerticalEditFieldValueChanged(app, event)
            value = app.VerticalEditField.Value;
            if value > 0
                app.vRes = value;
                redraw(app);
            else
                errordlg('Value must be greater than 0');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Acoustic Pressure Slice')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PhasedelaysTab = uitab(app.TabGroup);
            app.PhasedelaysTab.Title = 'Phase delays';
            app.SourceDropDownLabel = uilabel(app.PhasedelaysTab);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.PhasedelaysTab);
            app.SourceDropDown.Items = {'workspace', '.mat file'};
            app.SourceDropDown.Value = 'workspace';
            app.ViewphasedelaysButton = uibutton(app.PhasedelaysTab, 'push');
            app.ViewphasedelaysButton.ButtonPushedFcn = createCallbackFcn(app, @ViewphasedelaysButtonPushed, true);
            app.ViewphasedelaysButton.Text = 'View phase delays';
            app.BrowseButton = uibutton(app.PhasedelaysTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse ...';
            app.SliceplaneTab = uitab(app.TabGroup);
            app.SliceplaneTab.Title = 'Slice plane';
            app.SliceDropDown = uidropdown(app.SliceplaneTab);
            app.SliceDropDown.Items = {'x', 'y', 'z'};
            app.SliceDropDown.ValueChangedFcn = createCallbackFcn(app, @SliceDropDownValueChanged, true);
            app.SliceDropDown.Value = 'x';
            app.Label = uilabel(app.SliceplaneTab);
            app.Label.Text = '=';
            app.SliceConstField = uieditfield(app.SliceplaneTab, 'numeric');
            app.SliceConstField.ValueChangedFcn = createCallbackFcn(app, @SliceConstFieldValueChanged, true);
            app.unitsLabel = uilabel(app.SliceplaneTab);
            app.unitsLabel.Text = 'units';
            app.AxeslimitsTab = uitab(app.TabGroup);
            app.AxeslimitsTab.Title = 'Axes limits';
            app.hLabel = uilabel(app.AxeslimitsTab);
            app.hLabel.Text = 'x';
            app.vLabel = uilabel(app.AxeslimitsTab);
            app.vLabel.Text = 'x';
            app.fromEditFieldLabel = uilabel(app.AxeslimitsTab);
            app.fromEditFieldLabel.Text = 'from';
            app.hStart = uieditfield(app.AxeslimitsTab, 'numeric');
            app.hStart.ValueChangedFcn = createCallbackFcn(app, @hStartValueChanged, true);
            app.fromEditFieldLabel_2 = uilabel(app.AxeslimitsTab);
            app.fromEditFieldLabel_2.Text = 'from';
            app.vStart = uieditfield(app.AxeslimitsTab, 'numeric');
            app.vStart.ValueChangedFcn = createCallbackFcn(app, @vStartValueChanged, true);
            app.toLabel = uilabel(app.AxeslimitsTab);
            app.toLabel.Text = 'to';
            app.hEnd = uieditfield(app.AxeslimitsTab, 'numeric');
            app.hEnd.ValueChangedFcn = createCallbackFcn(app, @hEndValueChanged, true);
            app.toLabel_2 = uilabel(app.AxeslimitsTab);
            app.toLabel_2.Text = 'to';
            app.vEnd = uieditfield(app.AxeslimitsTab, 'numeric');
            app.vEnd.ValueChangedFcn = createCallbackFcn(app, @vEndValueChanged, true);
            app.unitsLabel_2 = uilabel(app.AxeslimitsTab);
            app.unitsLabel_2.Text = 'units';
            app.unitsLabel_3 = uilabel(app.AxeslimitsTab);
            app.unitsLabel_3.Text = 'units';
            app.UnitsTab = uitab(app.TabGroup);
            app.UnitsTab.Title = 'Units';
            app.UnitsDropDownLabel = uilabel(app.UnitsTab);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.UnitsTab);
            app.UnitsDropDown.Items = {'mm', 'm'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'mm';
            app.ResolutionTab = uitab(app.TabGroup);
            app.ResolutionTab.Title = 'Resolution';
            app.HorizontalEditFieldLabel = uilabel(app.ResolutionTab);
            app.HorizontalEditFieldLabel.Text = 'Horizontal';
            app.HorizontalEditField = uieditfield(app.ResolutionTab, 'numeric');
            app.HorizontalEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalEditFieldValueChanged, true);
            app.pixelsLabel = uilabel(app.ResolutionTab);
            app.pixelsLabel.Text = 'pixels';
            app.pixelsLabel_2 = uilabel(app.ResolutionTab);
            app.pixelsLabel_2.Text = 'pixels';
            app.VerticalEditFieldLabel = uilabel(app.ResolutionTab);
            app.VerticalEditFieldLabel.Text = 'Vertical';
            app.VerticalEditField = uieditfield(app.ResolutionTab, 'numeric');
            app.VerticalEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = acousticPressureSlice

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end